classdef ColocThresh_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ThresholdMethodDropDown       matlab.ui.control.DropDown
        ThresholdMethodDropDownLabel  matlab.ui.control.Label
        PreviewButton                 matlab.ui.control.Button
        ViewOrig                      matlab.ui.control.Button
        SizeRangeCutoffPanel          matlab.ui.container.Panel
        SetCutoffsButton              matlab.ui.control.Button
        NoMaxSizeCheckBox             matlab.ui.control.CheckBox
        NoMinSizeCheckBox             matlab.ui.control.CheckBox
        MaxSize                       matlab.ui.control.NumericEditField
        MaxSizeLabel                  matlab.ui.control.Label
        MinSizeLabel                  matlab.ui.control.Label
        MinSize                       matlab.ui.control.NumericEditField
        SelectChannelDropDown         matlab.ui.control.DropDown
        SelectChannelLabel            matlab.ui.control.Label
        SaveOrigImg                   matlab.ui.control.CheckBox
        OutputCheckBox                matlab.ui.control.CheckBox
        DoneButton                    matlab.ui.control.Button
        ThresholdPanel                matlab.ui.container.Panel
        FillHolesCheckBox             matlab.ui.control.CheckBox
        ThreshModeCheckBox            matlab.ui.control.CheckBox
        SelectedvalueEditFieldLabel   matlab.ui.control.Label
        ThreshInput                   matlab.ui.control.NumericEditField
        ThreshSlider                  matlab.ui.control.Slider
        ThresholdLabel                matlab.ui.control.Label
        ThesholdSelectionPanel        matlab.ui.container.Panel
        instructions                  matlab.ui.control.Label
        NoiseImg                      matlab.ui.control.UIAxes
        OrigImg                       matlab.ui.control.UIAxes
        ThreshImg                     matlab.ui.control.UIAxes
    end

    % Properties added by user
    properties (Access = public)
        Output
        channelNames
        ShowImgs = true
        SaveOrig = true
        BinaryThresholdedImg
        adjustthresh
        setthresh
        size
        length
        voxscale
        CurrentChannel
        Mode = false
        ThreshMode struct = struct()%
        Fill = false
        FillHoles struct = struct() %
        ThresholdValues struct = struct()
        ThreshMethod struct = struct() %
        BinaryThresholdedImgs struct = struct()
        SizeCutoffs struct = struct()
        SizeFilteredImgs struct = struct()
        SizeFilteredImgMax struct = struct()
        InputImages struct = struct()
    end

    methods (Access = public)
                
        function setup(app, ColocThreshInput)
            app.length = ColocThreshInput.length;
            app.size = ColocThreshInput.size;
            app.voxscale = ColocThreshInput.voxscale;
            app.InputImages = ColocThreshInput.ColocImgs;
            
            % Setup dropdown menu with channel names and a placeholder
            app.channelNames = fieldnames(app.InputImages);
            dropdownItems = [{'<selection>'}, app.channelNames'];
            app.SelectChannelDropDown.Items = dropdownItems;
            app.SelectChannelDropDown.Value = '<selection>';

            app.FillHolesCheckBox.Enable = "off";
            app.ThreshModeCheckBox.Enable = "off";
            app.MaxSize.Enable = "off";
            app.MinSize.Enable = "off";
            app.NoMaxSizeCheckBox.Value = true;
            app.NoMinSizeCheckBox.Value = true;
           
        end

        function centerAppOnScreen(app)
            screenSize = get(0, 'ScreenSize');
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            left = screenSize(1) + (screenSize(3) - appWidth) / 2;
            bottom = screenSize(2) + (screenSize(4) - appHeight) / 2;
            app.UIFigure.Position(1:2) = [left, bottom];
        end
        
        function applyThreshold(app, val)
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Applying Threshold', ...
                'Message', 'Applying Threshold...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);

            selectedChannel = app.SelectChannelDropDown.Value;
            if strcmp(selectedChannel, '<selection>')
                close(d);
               uialert(app.UIFigure, ...
                    'You must select a channel from the "Select Channel" dropdown menu.', ...
                    'No channel selected');
                return;
            end
            img3D = app.InputImages.(selectedChannel);
                d.Value = .2;
                drawnow;
                
            switch app.ThresholdMethodDropDown.Value
                case '<select method>'
                    close(d);
                    uialert(app.UIFigure, 'Please select a thresholding method first.', 'No Method Selected');
                    return;
                case 'Otsu'
                    level = graythresh(img3D(:));
                case 'Multi-Otsu'
                    level = multithresh(img3D(:));
                case 'Median'
                    level = median(img3D(:));
                case 'Mean'
                    level = mean(img3D(:));
            end


            if app.ThreshModeCheckBox.Value
                BinaryImg = zeros(app.size(1), app.size(2), app.length);
                for i = 1:app.length
                    BinaryImg(:, :, i) = imbinarize(img3D(:, :, i), level * val);
                end
            else
                BinaryImg = imbinarize(img3D, level * val);
            end
                d.Value = .4;
                drawnow;
                
            if app.Fill == true
                BinaryImg = bwmorph3(BinaryImg, "fill");
            end
                d.Value = .6;
                drawnow;

            im2D = max(BinaryImg, [], 3);
            imagesc(app.ThreshImg, im2D);
            axis(app.ThreshImg, 'image');
            colormap(app.ThreshImg, gray);
                d.Value = .8;
                drawnow;
            
            app.ThreshMethod.(selectedChannel) = app.ThresholdMethodDropDown.Value;
            app.ThreshMode.(selectedChannel) = app.Mode;
            app.FillHoles.(selectedChannel) = app.Fill;
            app.setthresh = val;
            app.ThresholdValues.(selectedChannel) = app.setthresh;
            app.BinaryThresholdedImgs.(selectedChannel) = BinaryImg;
                d.Value = 1;
                drawnow;
            close(d);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            centerAppOnScreen(app);
        end

        % Value changed function: SelectChannelDropDown
        function SelectChannelDropDownValueChanged(app, event)
            selectedChannel = app.SelectChannelDropDown.Value;
            if strcmp(selectedChannel, '<selection>')
                uialert(app.UIFigure, 'Please select a valid channel.', 'No Channel Selected');
                return;
            end
            img = app.InputImages.(selectedChannel);
            imshow(max(img, [], 3), [], 'Parent', app.OrigImg);
            axis(app.OrigImg, 'image');
            title(app.OrigImg, ['Max Projection: ', selectedChannel]);
            
            % reset threshold method and disable threshold box
            app.FillHolesCheckBox.Enable = "off";
            app.ThreshModeCheckBox.Enable = "off";
            app.ThreshModeCheckBox.Enable = "off";
            app.FillHolesCheckBox.Enable = "off";
            app.ThreshSlider.Enable = "off";
            app.ThreshInput.Enable = "off";
            app.ThresholdMethodDropDown.Value = "<select method>";

            % clear UIAxes
            cla(app.ThreshImg);
            cla(app.NoiseImg);
        end

        % Value changed function: ThresholdMethodDropDown
        function ThresholdMethodDropDownValueChanged(app, event)
            selectedChannel = app.SelectChannelDropDown.Value;
            method = app.ThresholdMethodDropDown.Value;
            app.ThreshMethod.(selectedChannel) = method;
            if isempty(app.setthresh) || isnan(app.setthresh)
            else
                val = app.setthresh;
                applyThreshold(app, val);
            end

            % enable threshold box options
            app.FillHolesCheckBox.Enable = "on";
            app.ThreshModeCheckBox.Enable = "on";
            app.ThreshModeCheckBox.Enable = "on";
            app.FillHolesCheckBox.Enable = "on";
            app.ThreshSlider.Enable = "on";
            app.ThreshInput.Enable = "on";
        end

        % Button pushed function: ViewOrig
        function ViewOrigButtonPushed(app, event)
            channelName = app.SelectChannelDropDown.Value;
            d = uiprogressdlg(app.UIFigure, ...
                'Title', ['Rendering 3D preview for ', channelName], ...
                'Message', 'Rendering...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);

            if strcmp(channelName, '<selection>')
                close(d);
                uialert(app.UIFigure, 'Please select a valid channel.', 'No Channel Selected');
                return;
            end

            % Show using volshow
            viewer = volshow(app.InputImages.(channelName), 'Colormap', [1 1 1]);
                d.Value = .3;
                drawnow;
            fig = ancestor(viewer, 'figure');
                d.Value = .7;
                drawnow;
            fig.Name = sprintf('Original Image: %s', channelName);
                d.Value = 1;
                drawnow;
            close(d);
        end

        % Value changed function: ThreshSlider
        function ThreshSliderValueChanged(app, event)
            val = round(app.ThreshSlider.Value, 2); 
            app.ThreshInput.Value = val; % Sync the numeric input
            applyThreshold(app, val); % Apply threshold logic
            app.ThreshModeCheckBox.Enable = "on";
            app.FillHolesCheckBox.Enable = "on";
        end

        % Value changed function: ThreshInput
        function ThreshInputValueChanged(app, event)
            val = round(app.ThreshInput.Value, 2);
            app.ThreshSlider.Value = val; % Sync the slider input
            applyThreshold(app, val); % Apply threshold logic
            app.ThreshModeCheckBox.Enable = "on";
            app.FillHolesCheckBox.Enable = "on";
        end

        % Value changed function: ThreshModeCheckBox
        function ThreshModeCheckBoxValueChanged(app, event)
            ThreshModeBox = app.ThreshModeCheckBox.Value;
            app.Mode = ThreshModeBox;
            applyThreshold(app, app.setthresh);
        end

        % Value changed function: FillHolesCheckBox
        function FillHolesCheckBoxValueChanged(app, event)
            FillBox = app.FillHolesCheckBox.Value;
            app.Fill = FillBox;
            applyThreshold(app, app.setthresh);
        end

        % Value changed function: NoMaxSizeCheckBox
        function NoMaxSizeCheckBoxValueChanged(app, event)
            app.MaxSize.Enable = ~app.NoMaxSizeCheckBox.Value;
        end

        % Value changed function: NoMinSizeCheckBox
        function NoMinSizeCheckBoxValueChanged(app, event)
            app.MinSize.Enable = ~app.NoMinSizeCheckBox.Value;
        end

        % Button pushed function: SetCutoffsButton
        function SetCutoffsButtonPushed(app, event)
            channelName = app.SelectChannelDropDown.Value;
            d = uiprogressdlg(app.UIFigure, ...
                'Title', 'Setting Size Cutoffs', ...
                'Message', ['Setting size cutoffs for ', channelName], ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);
    
            % Make sure valid channel is selected
            if strcmp(channelName, '<selection>')
                close(d);
                uialert(app.UIFigure, 'Please select a valid channel before setting cutoffs.', ...
                        'No Channel Selected');
                return;
            end

            % Make sure threshold is defined and valid
            if ~isfield(app.ThresholdValues, channelName)
                close(d);
                uialert(app.UIFigure, 'You must specify and set the threshold before setting size filter cutoffs', ...
                    'No Threshold Set');
                return;
            end
            
            if isempty(app.ThresholdValues.(channelName)) || isnan(app.ThresholdValues.(channelName))
                close(d);
                uialert(app.UIFigure, 'You must specify and set the threshold before setting size filter cutoffs', ...
                    'No Threshold Set');
                return;
            end
                d.Value =.2;
                drawnow;

            % Check if "no min size" is selected
            if app.NoMinSizeCheckBox.Value
                minSize = 0;  % no minimum filter
            else
                minSize = app.MinSize.Value;
            end
                d.Value =.3;
                drawnow;

            % Check if "no max size" is selected
            if app.NoMaxSizeCheckBox.Value
                maxSize = Inf;  % no maximum filter
            else
                maxSize = app.MaxSize.Value;
            end
                d.Value =.4;
                drawnow;

            % Validate that min <= max
            if minSize > maxSize
                if isgraphics(h)
                    close(h);
                end
                uialert(app.UIFigure, 'Minimum size cannot be greater than maximum size.', ...
                        'Invalid Size Range');
                return;
            end
                d.Value =.5;
                drawnow;

            % Store size cutoffs for this channel
            app.SizeCutoffs.(channelName) = struct('Min', minSize, 'Max', maxSize);
                d.Value =.6;
                drawnow;

            % Get binary image and voxel scale
            BW = app.BinaryThresholdedImgs.(channelName);
            sz = app.SizeCutoffs.(channelName);
                d.Value =.7;
                drawnow;
        
            % Find connected components in 3D
            CC = bwconncomp(BW, 26);
            stats = regionprops3(CC, 'Volume');
            volumes = stats.Volume * app.voxscale;
            keepIdx = find(volumes >= sz.Min & volumes <= sz.Max);
                d.Value =.8;
                drawnow;
        
            % Create filtered mask
            L = labelmatrix(CC);
            FilteredBW = ismember(L, keepIdx);
            if isempty(app.SizeFilteredImgs)
                app.SizeFilteredImgs = struct();
            end
            app.SizeFilteredImgs.(channelName) = FilteredBW;
                d.Value =.9;
                drawnow;

            % Show in UIAxes
            if isempty(app.SizeFilteredImgMax)
                app.SizeFilteredImgMax = struct();
            end
            app.SizeFilteredImgMax.(channelName) = max(app.SizeFilteredImgs.(channelName), [], 3);
            imshow(app.SizeFilteredImgMax.(channelName), 'Parent', app.NoiseImg);
            axis(app.NoiseImg, 'image');
                d.Value = 1;
                drawnow;
            close(d);
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            channelName = app.SelectChannelDropDown.Value;
            d = uiprogressdlg(app.UIFigure, ...
                'Title', ['Rendering 3D preview for ', channelName], ...
                'Message', 'Rendering...', ...
                'Indeterminate', 'off', ...
                'Cancelable', 'off', ...
                'Value', 0);

            % Check that Threshold Value has been set
            if ~isfield(app.ThresholdValues, channelName)
                close(d);
                uialert(app.UIFigure, 'Please set threshold before previewing in 3D.', ...
                        'No Threshold Set');
                return;
            end
                d.Value = .25;
                drawnow;

            % Check that Min/Max Cutoffs are set
            if ~isfield(app.SizeCutoffs, channelName) || ...
               ~isfield(app.SizeCutoffs.(channelName), 'Min') || ...
               ~isfield(app.SizeCutoffs.(channelName), 'Max')
                close(d);
                uialert(app.UIFigure, 'Please set Min and Max size cutoffs before previewing in 3D.', ...
                        'No Size Cutoffs Set');
                return;
            end

            if strcmp(channelName, '<selection>')
                close(d);
                uialert(app.UIFigure, 'Please select a valid channel.', 'No Channel Selected');
                return;
            end
                d.Value = .5;
                drawnow;

            % Show using volshow
            viewer = volshow(app.SizeFilteredImgs.(channelName), 'Colormap', [1 1 1]);
                d.Value = .75;
                drawnow;
            fig = ancestor(viewer, 'figure');
            fig.Name = sprintf(['Filtered Volume: %s  ', ...
                                '[Threshold: %.2f, Min Size: %.2f µm³, Max Size: %.2f µm³]'], ...
                                channelName, app.ThresholdValues.(channelName), app.SizeCutoffs.(channelName).Min, app.SizeCutoffs.(channelName).Max);
                d.Value = 1;
                drawnow;
            close(d);
        end

        % Value changed function: SaveOrigImg
        function SaveOrigImgValueChanged(app, event)
            Origbox = app.SaveOrigImg.Value;
            app.SaveOrig = Origbox;
        end

        % Value changed function: OutputCheckBox
        function OutputCheckBoxValueChanged(app, event)
            Threshbox = app.OutputCheckBox.Value;
            app.ShowImgs = Threshbox;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Check that thresholds have been set for all channels before exiting
            missingThreshold = {};
            for i = 1:numel(app.channelNames)
                ch = app.channelNames{i};
                if ~isfield(app.ThresholdValues, ch)
                    missingThreshold{end+1} = ch;
                end
            end
            
            % If any missing or incomplete, show alert
            if ~isempty(missingThreshold)
                msg = sprintf(['The following channel(s) do not a vaid threshold set:\n\n%s\n\n' ...
                    'Please set threshold values for each.'], strjoin(missingThreshold, ', '));
                
                uialert(app.UIFigure, msg, 'Missing Threshold');
                return;
            end

            % Check that size cutoffs have been set for all channels before exiting
            missingChannels = {};
            for i = 1:numel(app.channelNames)
                ch = app.channelNames{i};
                if ~isfield(app.SizeCutoffs, ch) || ...
                   ~isfield(app.SizeCutoffs.(ch), 'Min') || ...
                   ~isfield(app.SizeCutoffs.(ch), 'Max')
                    missingChannels{end+1} = ch;
                end
            end
            
            % If any missing or incomplete, show alert
            if ~isempty(missingChannels)
                msg = sprintf(['The following channel(s) do not have valid size cutoffs set:\n\n%s\n\n' ...
                    'Please set both Min and Max values for each.'], strjoin(missingChannels, ', '));
                
                uialert(app.UIFigure, msg, 'Missing Size Cutoffs');
                return;
            end
            
            assignin('base', 'ColocShowImg', app.ShowImgs);
            assignin('base', 'ColocSaveOrig', app.SaveOrig);
            assignin('base', 'ColocThresholdValues', app.ThresholdValues);
            assignin('base', 'ColocSizeCutoffs', app.SizeCutoffs);
            assignin('base', 'ColocSizeFilteredImgs', app.SizeFilteredImgs);
            assignin('base', 'ColocThreshMode', app.ThreshMode);
            assignin('base', 'ColocThreshMethod', app.ThreshMethod);
            assignin('base', 'ColocFillHoles', app.FillHoles);

            app.Output = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThreshImg = uiaxes(app.UIFigure);
            title(app.ThreshImg, 'Thresholded Image')
            app.ThreshImg.XTick = [];
            app.ThreshImg.YTick = [];
            app.ThreshImg.ZTick = [];
            app.OrigImg = uiaxes(app.UIFigure);
            title(app.OrigImg, 'Max Projection')
            app.OrigImg.XTick = [];
            app.OrigImg.YTick = [];
            app.OrigImg.ZTick = [];
            app.NoiseImg = uiaxes(app.UIFigure);
            title(app.NoiseImg, 'Size Filtered Image')
            app.NoiseImg.XTick = [];
            app.NoiseImg.YTick = [];
            app.NoiseImg.ZTick = [];
            app.ThesholdSelectionPanel = uipanel(app.UIFigure);
            app.ThesholdSelectionPanel.Title = 'Theshold Selection';
            app.instructions = uilabel(app.ThesholdSelectionPanel);
            app.instructions.Text = {'1) Use the maximum intensity projection of your original image on the left as a reference to help with thresholding. Optional: press ''View original image in 3D'' for volumetric view in a pop-out window.'; '2) Select your desired threshold using the slider/input. Press ''Try this…'' to pass the information on for noise filtering. '; '3) Use the ‘Size Range Cutoff’ sliders/inputs to select the minimum and maximum object sizes you’d like to filter out. Press ''Set Size Range Cutoffs'' to see the result rendered in 3D in a pop-out window. Continue adjusting your threshold and noise filter values until you’re happy. Remember to press ‘Try this’ or ‘Update’ after adjusting to set the new value. Each time you press ''Update'' a new preview of your 3D image will be produced so you can compare to previous Threshold/Noise Filter combinations and to the original 3D image. '; '4) Press the ''How many objects is this identifying'' button. '; '5) Choose whether to output thresholded image and/or a maximum intensity projection of the original image for reference.'; '6) Press the ''Done'' button when you are satisfied with the 3D preview of your thresholded and noise filtered image.'};
            app.ThresholdPanel = uipanel(app.UIFigure);
            app.ThresholdPanel.Title = 'Threshold';
            app.ThresholdLabel = uilabel(app.ThresholdPanel);
            app.ThresholdLabel.Text = 'Threshold';
            app.ThreshSlider = uislider(app.ThresholdPanel);
            app.ThreshSlider.Limits = [0 10];
            app.ThreshSlider.MajorTicks = [];
            app.ThreshSlider.ValueChangedFcn = createCallbackFcn(app, @ThreshSliderValueChanged, true);
            app.ThreshSlider.Step = 0.001;
            app.ThreshInput = uieditfield(app.ThresholdPanel, 'numeric');
            app.ThreshInput.Limits = [0 10];
            app.ThreshInput.ValueDisplayFormat = '%.3f';
            app.ThreshInput.ValueChangedFcn = createCallbackFcn(app, @ThreshInputValueChanged, true);
            app.SelectedvalueEditFieldLabel = uilabel(app.ThresholdPanel);
            app.SelectedvalueEditFieldLabel.Text = 'Selected value';
            app.ThreshModeCheckBox = uicheckbox(app.ThresholdPanel);
            app.ThreshModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThreshModeCheckBoxValueChanged, true);
            app.ThreshModeCheckBox.Text = 'Apply to z-slices separately';
            app.FillHolesCheckBox = uicheckbox(app.ThresholdPanel);
            app.FillHolesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillHolesCheckBoxValueChanged, true);
            app.FillHolesCheckBox.Text = 'Fill Holes';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.OutputCheckBox = uicheckbox(app.UIFigure);
            app.OutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputCheckBoxValueChanged, true);
            app.OutputCheckBox.Text = 'Save threshold/filtered images';
            app.OutputCheckBox.Value = true;
            app.SaveOrigImg = uicheckbox(app.UIFigure);
            app.SaveOrigImg.ValueChangedFcn = createCallbackFcn(app, @SaveOrigImgValueChanged, true);
            app.SaveOrigImg.Text = 'Save original images';
            app.SaveOrigImg.Value = true;
            app.SelectChannelLabel = uilabel(app.UIFigure);
            app.SelectChannelLabel.Text = 'Select Channel:';
            app.SelectChannelDropDown = uidropdown(app.UIFigure);
            app.SelectChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectChannelDropDownValueChanged, true);
            app.SizeRangeCutoffPanel = uipanel(app.UIFigure);
            app.SizeRangeCutoffPanel.Title = 'Size Range Cutoff';
            app.MinSize = uieditfield(app.SizeRangeCutoffPanel, 'numeric');
            app.MinSize.Limits = [0 Inf];
            app.MinSizeLabel = uilabel(app.SizeRangeCutoffPanel);
            app.MinSizeLabel.Text = 'Min Size (µm³)';
            app.MaxSizeLabel = uilabel(app.SizeRangeCutoffPanel);
            app.MaxSizeLabel.Text = 'Max Size (µm³)';
            app.MaxSize = uieditfield(app.SizeRangeCutoffPanel, 'numeric');
            app.MaxSize.Limits = [0 Inf];
            app.NoMinSizeCheckBox = uicheckbox(app.SizeRangeCutoffPanel);
            app.NoMinSizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoMinSizeCheckBoxValueChanged, true);
            app.NoMinSizeCheckBox.Text = 'No Min Size';
            app.NoMaxSizeCheckBox = uicheckbox(app.SizeRangeCutoffPanel);
            app.NoMaxSizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoMaxSizeCheckBoxValueChanged, true);
            app.NoMaxSizeCheckBox.Text = 'No Max Size';
            app.SetCutoffsButton = uibutton(app.SizeRangeCutoffPanel, 'push');
            app.SetCutoffsButton.ButtonPushedFcn = createCallbackFcn(app, @SetCutoffsButtonPushed, true);
            app.SetCutoffsButton.Text = 'Set Size Range Cutoffs';
            app.ViewOrig = uibutton(app.UIFigure, 'push');
            app.ViewOrig.ButtonPushedFcn = createCallbackFcn(app, @ViewOrigButtonPushed, true);
            app.ViewOrig.Text = 'View original image in 3D';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview in 3D';
            app.ThresholdMethodDropDownLabel = uilabel(app.UIFigure);
            app.ThresholdMethodDropDownLabel.Text = 'Threshold Method';
            app.ThresholdMethodDropDown = uidropdown(app.UIFigure);
            app.ThresholdMethodDropDown.Items = {'<select method>', 'Otsu', 'Multi-Otsu', 'Median', 'Mean'};
            app.ThresholdMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdMethodDropDownValueChanged, true);
            app.ThresholdMethodDropDown.Value = '<select method>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColocThresh_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end